classdef VIView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ClearAllButton                  matlab.ui.control.Button
        ProgressEditField5              matlab.ui.control.EditField
        ProgressEditField4              matlab.ui.control.EditField
        ProgressEditField3              matlab.ui.control.EditField
        ProgressEditField2              matlab.ui.control.EditField
        ProgressEditField1              matlab.ui.control.EditField
        ProgressBarLabel                matlab.ui.control.Label
        ComputeButton                   matlab.ui.control.Button
        ExtractSolutionVecsCheckBox     matlab.ui.control.CheckBox
        TimestepsEditField              matlab.ui.control.NumericEditField
        TimestepsEditFieldLabel         matlab.ui.control.Label
        secsLabel                       matlab.ui.control.Label
        MaxTimeEditField                matlab.ui.control.NumericEditField
        MaxTimeEditFieldLabel           matlab.ui.control.Label
        metersLabel2                    matlab.ui.control.Label
        CharLengthEditField             matlab.ui.control.NumericEditField
        OhmsLabel3                      matlab.ui.control.Label
        CharLengthEditFieldLabel        matlab.ui.control.Label
        CharImpedanceEditField          matlab.ui.control.NumericEditField
        CharImpedanceEditFieldLabel     matlab.ui.control.Label
        CharPeakVoltageLabel            matlab.ui.control.Label
        CharPeakVoltageEditField        matlab.ui.control.NumericEditField
        CharPeakVoltageEditField_2Label  matlab.ui.control.Label
        LoadPlotsLabel                  matlab.ui.control.Label
        LoadPlotsCheckBox               matlab.ui.control.CheckBox
        ProbeLocationLabel              matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ProbeLocationEditField          matlab.ui.control.EditField
        ProbeLocationEditFieldLabel     matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        DeleteElementButton             matlab.ui.control.Button
        AddElementButton                matlab.ui.control.Button
        ofPointsEditField               matlab.ui.control.NumericEditField
        ofPointsEditFieldLabel          matlab.ui.control.Label
        unondimEditField                matlab.ui.control.NumericEditField
        unondimEditFieldLabel           matlab.ui.control.Label
        OhmsLabel2                      matlab.ui.control.Label
        CableZcEditField                matlab.ui.control.NumericEditField
        CableZcEditFieldLabel           matlab.ui.control.Label
        metersLabel                     matlab.ui.control.Label
        CableLengthEditField            matlab.ui.control.NumericEditField
        CableLengthEditFieldLabel       matlab.ui.control.Label
        VLabel                          matlab.ui.control.Label
        VbreakdownEditField             matlab.ui.control.NumericEditField
        VbreakdownEditFieldLabel        matlab.ui.control.Label
        FaradsLabel                     matlab.ui.control.Label
        CEditField                      matlab.ui.control.NumericEditField
        CEditFieldLabel                 matlab.ui.control.Label
        REditField                      matlab.ui.control.NumericEditField
        OhmsLabel                       matlab.ui.control.Label
        REditFieldLabel                 matlab.ui.control.Label
        ElementTypeDropDown             matlab.ui.control.DropDown
        ElementTypeDropDownLabel        matlab.ui.control.Label
        SampleGaussianCheckBox          matlab.ui.control.CheckBox
        VoltageInputDataEditField       matlab.ui.control.EditField
        VoltageInputDataEditFieldLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        RightPanel                      matlab.ui.container.Panel
        TimeSliderLabel_3               matlab.ui.control.Label
        TimeSliderLabel_2               matlab.ui.control.Label
        PlayButton                      matlab.ui.control.Button
        TimeSlider                      matlab.ui.control.Slider
        TimeSliderLabel                 matlab.ui.control.Label
        ProbeLocationLabel_2            matlab.ui.control.Label
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes1                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Last Updated: 05/02/2024
    % By: Sankarsh Rao (srr2949@mit.edu)
    properties (Access = private)
        data; % data that is stored in a table to share between callbacks
        volt; % voltage to plot movie
        curr; % current to plot movie
        X;
        Breaks;
        DimensionObject;
        CableSystemObject;
        V0; % characteristic voltage (peak voltage supplied by pulser)
        uc = 2.99792e8; % characteristic speed (speed of light)
        Zc; %characteristic impedance (ideal transmission cable)
        L; %characteristic length to normalize over
        Np = 1001;
        legendCell = {};
        blue = [0.07,0.62,1.00]; % progress bar color
 
    end
    
    methods (Access = private)
        
        function teleMain(app)
            %load in user input data and cast to global properties for
            %necessary ones
            %tic
            try
                app.V0 = app.CharPeakVoltageEditField.Value;
                app.Zc = app.CharImpedanceEditField.Value;
                app.L = app.CharLengthEditField.Value;
                tableData = get(app.UITable, 'Data');
                typeVec = string(tableData(:, 1))';
                Lengths = cell2mat(tableData(:, 2))';
                R = cell2mat(tableData(:, 3))';
                u = cell2mat(tableData(:, 4))';
                rload = cell2mat(tableData(:, 5))';
                cload = cell2mat(tableData(:, 6))';
                nPoints = cell2mat(tableData(:, 7))';
                
                %format input vectors in correct way
                R = R(R>0);
                u = u(u>0);
                CableLengths = Lengths(Lengths > 0);
                nPoints = nPoints(nPoints > 0);
                gridSpace = CableLengths./nPoints;
                
                %add grounding cable if last element is not a cable
                if typeVec(end) ~= "Cable"
                    CableLengths = [CableLengths 1e-3];
                    R = [R app.Zc];
                    u = [u u(end)];
                    nPoints = [nPoints 1];
                    gridSpace = CableLengths./nPoints;
                    typeVec = [typeVec "Cable"];
                    rload = [rload 0];
                    cload = [cload 0];
                end

                %have to format differently due to assumed 0 loads at Cable
                %interfaces
                [rload, cload] = constructLoads(app, rload, cload, typeVec);


                %Dimensions class to make non-dimensionalization and
                %normalization easy
                app.DimensionObject = Dimensions(app.V0, app.Zc, app.uc, app.L);
                D = app.DimensionObject;
                
                %Non-dimensionalize Cable and load information
                Lc = D.noDim_len(CableLengths);
                Rc = D.noDim_R(R);
                dx = D.noDim_len(gridSpace);
                
                RL = D.noDim_R(rload);
                CL = D.noDim_C(cload);
    
                %Simulation information
                tmax = D.noDim_t(app.MaxTimeEditField.Value);
                if app.SampleGaussianCheckBox.Value == 1
                    Vp = @(t) exp(-(t-35./33.4).^2./0.1291); %(exp(-(t-1.5).^2/0.2));
                else
                    pulse_file = convertCharsToStrings(app.VoltageInputDataEditField.Value);
                    Vp = loadPulse(app,pulse_file,D);
                end

            catch
                app.ProgressBarLabel.Text = 'ERROR: INPUTS -- please check inputs';
                return;
            end
            app.ProgressEditField1.BackgroundColor = app.blue;
            pause(0.01);
            
            %CableSystem class to construct the network of Cables and loads
            %with associated properties
            try
                app.CableSystemObject = CableSystem(Lc, Rc, u, RL, CL, dx);
                WS = app.CableSystemObject;
            catch
                app.ProgressBarLabel.Text = 'ERROR: CABLESYSTEM -- please check inputs, normalization values, etc.';
                return;
            end
            app.ProgressEditField2.BackgroundColor = app.blue;
            app.ProgressBarLabel.Text = 'Inputs Loaded into CableSystem! Solving ODE...';
            pause(0.01);

            
            %Total number of spatial simulation points
            N = sum(WS.Nx);
            
            locations = strsplit(app.ProbeLocationEditField.Value, ', ');
            locations = str2double(locations);
        
            breaks = cumsum(CableLengths);

            tries = 1;
            
            if sum(contains(typeVec, "Spark Gap")) > 0
                while true
                    try
                        %tries
                        b=@(t)[Vp(t);
                        zeros(2*N-1,1)];
                    
                        J0=jacobian(app,0,b(0),WS);
                        M0=mass(app,0,b(0),WS);
                        [state0,dstate0]=consistentIC(app,J0,M0,b(0),WS);
                        
                        time = 0.0;
                        cnt = 0;
                        sol = {};
                    
                        rInd = find(WS.CL > 0);
                    
                        WS.RL(rInd) = 50e6./app.Zc;
                        Vbreak = app.VbreakdownEditField.Value./app.V0;
                        SE=@(t,y) spark_event(app,t,y,WS,rInd,Vbreak,1);
                        cnt = cnt + 1;
                        
                        J=jacobian(app,time,state0,WS);
                        M=mass(app,time,state0,WS);
                        [state0,dstate0]=consistentIC(app,J,M,state0,WS);
                        
                        ode=@(t,y) J*y - b(t);
                        options=odeset('Mass',M,'MassSingular','yes',...
                            'RelTol',1e-8,'AbsTol',1e-6,...
                            'InitialSlope',dstate0,...
                            'Jacobian',J,...
                            'Events', SE);
                        
                        sol{cnt}=ode15s(ode,[time tmax],state0, options);
                        time=sol{cnt}.x(end);  
                        state0=sol{cnt}.ye;
                        
                        WS.RL(rInd) = 10./app.Zc;
                        cnt = cnt + 1;
                        J=jacobian(app,time,state0,WS);
                        M=mass(app,time,state0,WS);
                        [state0,dstate0]=consistentIC(app,J,M,state0,WS);
                    
                        ode=@(t,y) J*y - b(t);
                        options=odeset('Mass',M,'MassSingular','yes',...
                        'RelTol',1e-8,'AbsTol',1e-6,...
                        'InitialSlope',dstate0,...
                        'Jacobian',J);
                        sol{cnt} = ode15s(ode,[time tmax],state0,options);
                        break;
                    catch
                        app.ProgressBarLabel.Text = ['Error in ode15s -- ' ...
                            'trying different spatial resolutions...'];

                        nPoints(end) = nPoints(end) + 1;
                        gridSpace = CableLengths./nPoints;
                        dx = D.noDim_len(gridSpace);
                        app.CableSystemObject = CableSystem(Lc, Rc, u, RL, CL, dx);
                        WS = app.CableSystemObject;
                        N = sum(WS.Nx);

                        tries = tries + 1;
                        if tries == 10
                            app.ProgressBarLabel.Text = ['ERROR: ODE SOLVE -- ' ...
                            'please modify inputs'];
                           return;
                        end
                        continue;
                    end
                end

                app.ProgressEditField3.BackgroundColor = app.blue;
                app.ProgressBarLabel.Text = 'ODE Solved! Parsing solution to plot...';
                pause(0.01);

                time=sol{cnt}.x(end);
                
                hold(app.UIAxes1, 'on'); hold(app.UIAxes2, 'on'); hold(app.UIAxes4, 'on');
                plot(app.UIAxes1, [D.reDim_t(sol{1}.xe).*10^9;D.reDim_t(sol{1}.xe).*10^9], [-2;2],'--k');
                plot(app.UIAxes2, [D.reDim_t(sol{1}.xe).*10^9;D.reDim_t(sol{1}.xe).*10^9], [-2;2],'--k');
                plot(app.UIAxes4, [D.reDim_t(sol{1}.xe).*10^9;D.reDim_t(sol{1}.xe).*10^9], [-2;2],'--k');

                app.legendCell{1} = 'Spark Starts';
                
            else
                while true
                    try
                        b=@(t)[Vp(t);
                        zeros(2*N-1,1)];
                    
                        J0=jacobian(app,0,b(0),WS);
                        M0=mass(app,0,b(0),WS);
                        [state0,dstate0]=consistentIC(app,J0,M0,b(0),WS);
                        
                        time=0.0;
                        cnt=0;
                        sol={};
    
                        J=jacobian(app,time,state0,WS);
                        M=mass(app,time,state0,WS);
                        [state0,dstate0]=consistentIC(app,J,M,state0,WS);
                        ode=@(t,y) J*y - b(t);
                        
                        options=odeset('Mass',M,'MassSingular','yes',...
                        'RelTol',1e-8,'AbsTol',1e-6,...
                        'InitialSlope',dstate0,...
                        'Jacobian',J); % most restrictive should work for all examples
                        if app.TimestepsEditField.Value > 0
                            tspan = linspace(time, tmax, app.TimestepsEditField.Value);
                            sol{1}=ode15s(ode, tspan, state0, options);
                        else
                            sol{1}=ode15s(ode, [time tmax], state0, options);
                        end
                        break;

                    catch
                        app.ProgressBarLabel.Text = ['ERROR: ODE SOLVE -- ' ...
                            'trying different resolutions...'];
                        nPoints(end) = nPoints(end) + 1;
                        gridSpace = CableLengths./nPoints;
                        dx = D.noDim_len(gridSpace);
                        app.CableSystemObject = CableSystem(Lc, Rc, u, RL, CL, dx);
                        WS = app.CableSystemObject;
                        N = sum(WS.Nx);

                        tries = tries + 1;
                        if tries == 10
                            app.ProgressBarLabel.Text = ['ERROR: ODE SOLVE -- ' ...
                            'please modify inputs'];
                           return;
                        end
                    end
                end

                app.ProgressEditField3.BackgroundColor = app.blue;
                app.ProgressBarLabel.Text = 'ODE Solved! Parsing data to plot...';                
                pause(0.01);
                
            end

            tgrid=linspace(0,tmax,app.Np);
            cnt = 1; ind = 1; y = [];
            
            while cnt <= length(sol)
                maxind = find(tgrid >= sol{cnt}.x(end), 1) - 1;
                if cnt == length(sol)
                    maxind = maxind + 1;
                end
                y(:, ind:maxind) = deval(sol{cnt}, tgrid(ind:maxind));
                ind = maxind + 1;
                cnt = cnt + 1;
            end
            
            app.ProgressEditField4.BackgroundColor = app.blue;
            app.ProgressBarLabel.Text = 'Plotting...';
            pause(0.01);

            try       
                if ~isnan(locations)
                    plotProbe(app, sol, locations, WS, D, breaks, dx);
                end
    
                if app.LoadPlotsCheckBox.Value == 1
                    loadLocations = findLoads(app, Lengths);
                    plotLoad(app, sol, loadLocations, WS, D, breaks);
                end
    
                legend(app.UIAxes1, app.legendCell);
                legend(app.UIAxes2, app.legendCell);
                legend(app.UIAxes4, app.legendCell);
                hold(app.UIAxes1, "off");
                hold(app.UIAxes2, "off");
                hold(app.UIAxes4, "off");
                
                breaks = breaks(1:end-1);
                
                V=y(1:N, :);
                I=y(N+1:end, :);
                
                if app.ExtractSolutionVecsCheckBox.Value == 1
                    Iout = I;
                    Vout = V;
                    tout = tgrid;
                    xout = WS.x';
                    save("VIViewOut.mat", "Iout", "Vout", "tout", "xout");
                end
                
                plot(app.UIAxes3, WS.x', V(:, 1)); hold(app.UIAxes3, "on");
                plot(app.UIAxes3, WS.x', I(:, 1));
                breaks = D.noDim_len(breaks);
                        
                for i = 1:length(breaks)
                    plot(app.UIAxes3, [breaks(i);breaks(i)], [-2;2], '--k');
                end
                
                hold(app.UIAxes3, "off");
                app.volt = V;
                app.curr = I;
                app.X = WS.x';
                app.Breaks = breaks;
                app.ProgressEditField5.BackgroundColor = app.blue;
                app.ProgressBarLabel.Text = 'Done!';
            catch
                app.ProgressBarLabel.Text = 'ERROR: PLOTTING -- please modify plotting inputs';
                return;
            end
            %toc
            %tries
        end
        
        function [J] = jacobian(app,t,state,WS)
            %JACOBIAN Summary of this function goes here
            %   Detailed explanation goes here
        
            %% diagonal constructors for 2nd order FD with first order on boundaries
            Ntot=sum(WS.Nx);
            is=1;
            main=zeros(Ntot,1);
            up=main;
            down=main;
            nil=main;
            for i=1:length(WS.Nx)
                ie=is+WS.Nx(i)-1;   
                dx=WS.dx(i);
                main(is:ie)=zeros(WS.Nx(i),1);
                up(is:ie)=1./([1;dx;2*dx.*ones(WS.Nx(i)-2,1)]);
                down(is:ie)=-1./([2*dx.*ones(WS.Nx(i)-2,1);dx;1]);
                main(is)=-1/dx;
                main(ie)=1/dx;
                up(is)=0;
                down(ie)=0;
                is=ie+1;
            end
            % differential operator matrix
            Ddiag=[[main;main],[up;up],[down;down]];
            MD=spdiags(Ddiag,[0,1,-1],2*Ntot,2*Ntot);
        
            %% Coefficient Matrix
            % coefficient matrix
            Aup=zeros(Ntot,1);
            Adown=zeros(Ntot,1);
            is=1;
            for i=1:length(WS.Nx)
                ie=is+WS.Nx(i)-1;   
                u=WS.u(i);
                R=WS.Rc(i);
                Aup(is:ie)=u*R*ones(WS.Nx(i),1);
                Adown(is:ie)=u/R*ones(WS.Nx(i),1);
                is=ie+1;
            end
            Adiag=-[[nil;Aup],...
                [Adown;nil]];
            MA=spdiags(Adiag,[Ntot,-Ntot],2*Ntot,2*Ntot);
            
            %% Construct Full Matrix (no BCs)
            J=MA*MD;
            
            %% Now add in the BCs
            % these require a mass matrix formulation
            % Voltage at x=0 is prescibred
            J(1,:)=0;
            J(1,1)=1;
            
            % Contact with ground is 0 Voltage prescribed
            J(Ntot,:)=0;
            J(Ntot,Ntot)=1;
            
            for i=1:WS.Num_Load
                % always have current continuity
                row=Ntot+sum(WS.Nx(1:i))+1;
                J(row,:)=0; % over-write anything here
                J(row,row-1)=1;
                J(row,row)=-1;
                
                % now apply load condition
                row=sum(WS.Nx(1:i));
                J(row,:)=0; % over-write anything here
                J(row,row+Ntot)=WS.RL(i); % coefficient on current
                J(row,row)=-1; % coefficient on upstream voltage
                J(row,row+1)=1; % coefficient on downstream voltage
            end
            
        end
        
        function [M] = mass(app,t,state,WS)
        %UNTITLED Summary of this function goes here
        %   Detailed explanation goes here
            Ntot=sum(WS.Nx);
            M=speye(2*Ntot);
            % row 1 - specified voltage
            M(1,1)=0;
            % Ground connection
            M(Ntot,:)=0;
            
            for i=1:WS.Num_Load
                % Current Continuity
                row=sum(WS.Nx(1:i))+Ntot+1;
                M(row,row)=0;
                % Load Condition
                row=sum(WS.Nx(1:i));
                M(row,row)=WS.RL(i)*WS.CL(i); % over-write anything here
                M(row,row+1)=-WS.RL(i)*WS.CL(i); % coefficient on current
            end
        end
        
        function [u0,up0] = consistentIC(app,J,M,state0,WS)
            % finds a consistant IC for the equation M*u'=J*u+b
            
            % construct matrix of interest
            Mat1=[-J,M];
            Mat2=speye(size(Mat1,2));
            Mat=[Mat1;Mat2(1:size(Mat1,1),:)];
            N=sum(WS.Nx);
            
            % identify columns of all zeros
            % this finds all boundary condition rows except 1
            [~,col]=find(Mat);
            col=unique(col);
            inds=zeros(size(Mat,1)-length(col),1);
            cnt=0;
            for i=1:size(Mat,1)
                if ~any(i==col)
                    cnt=cnt+1;
                    inds(cnt)=i;
                end
            end
            
            for i=1:length(inds)
                Mat(inds(i),:)=0;
                Mat(inds(i),inds(i))=1;
            end
            
            b=[zeros(2*N,1);state0];
            b(1)=-b(2*N+1);
            b(2*N+1)=0;
            
            % find the consistent IC
            phi=Mat\b;
            u0=phi(1:2*N);
            up0=phi(2*N+1:end);
        end
        
        function [RHS] = ode(app, t, state, J, Vp_func)
            % sets up the ODE by returning the RHS of the ode 
            % offloaded to a function for readability
            b=zeros(length(state),1);
            b(1)=-Vp_func(t);
            RHS=J*state+b;
        end
        
        function [Vp] = loadPulse(app, filename, D)       
            % loads the experimental pulse
            
            load(filename)
            if exist('tp','var')
                t=tp; V=Vp;
            end
            
            % ensure numerical continuity for interpolation
            V(logical((t>0).*(V<0)))=0;
            
            % make signal start at t=0
            tp=t-t(1);
            
            % non-dimensionalize to comply with other calculations
            tp=D.noDim_t(tp); V=V/D.V0;
            
            % create continuous function via interpolation
            Vp=@(tq) interp1(tp,V,tq,'linear',0);
            
        end
        
        function plotProbe(app, odeSol, locations, WS, D, breaks, dx)
            % plots voltage and current for probe locations
            
            hold(app.UIAxes1, 'on');
            hold(app.UIAxes2, 'on');
            hold(app.UIAxes4, 'on');
            
            for i=1:length(locations)
                
                %for each location, find which cable the input is on
                %important since Cables can have different grid sizes
                [plotInd, modWL] = getplotInd(app, locations(i), breaks);
                
               
                % take whichever cable it is on, take the corresponding 
                % modded length and divide by the corresponding grid
                % spacing (dx) to find the index of the solution vector
                % that corresponds to the input location
                if plotInd == 1
                    yInd = 1 + round(D.noDim_len(modWL(plotInd))/dx(plotInd), 0);
                else   
                    yInd = sum(WS.Nx(1:plotInd-1)) + round(D.noDim_len(modWL(plotInd-1))/dx(plotInd), 0);  
                end
                
                Vprobe = []; Iprobe = []; Eprobe = []; t = [];
                %odeSol.y(1:sum(WS.Nx)) is voltage, 
                %odeSol.y(sum(WS.Nx)+1:end) is current. 
                for j = 1:length(odeSol)
                    Vprobe = [Vprobe odeSol{j}.y(yInd, :)];
                    Iprobe = [Iprobe odeSol{j}.y(yInd + sum(WS.Nx), :)];
                    t = [t odeSol{j}.x];
                    Eprobe = cumtrapz(t,Vprobe.*Iprobe);
                end
                plot(app.UIAxes1, D.reDim_t(t).*10^9, Vprobe);
                plot(app.UIAxes2, D.reDim_t(t).*10^9, Iprobe);
                plot(app.UIAxes4, D.reDim_t(t).*10^9, Eprobe);
                app.legendCell{end+1} = num2str(locations(i), '%-g m');
            end
            
            %add legends to plots
            %legend(app.UIAxes1, app.legendCell);
            %legend(app.UIAxes2, app.legendCell);
            %hold(app.UIAxes1, 'off');
            %hold(app.UIAxes2, 'off');
        end
        
        function plotLoad(app, odeSol, loadLocations, WS, D, breaks)
            % plots voltage DROP and current across loads in system
            
            hold(app.UIAxes1, 'on');
            hold(app.UIAxes2, 'on');
            hold(app.UIAxes4, 'on');
            
            for i=1:length(loadLocations)    
                %loads are 0 length, so no need to have mod lengths: sum up
                %grid points of all preceding Cables to find index of
                %solution vector that corresponds to the load
                [plotInd, ~] = getplotInd(app, loadLocations(i), breaks);
                yInd = sum(WS.Nx(1:plotInd-1));

                Vload = []; Iload = []; t = [];
                
                %odeSol.y(1:sum(WS.Nx)) is voltage, 
                %odeSol.y(sum(WS.Nx)+1:end) is current. 
                for j = 1:length(odeSol)
                    Vload = [Vload odeSol{j}.y(yInd, :) - odeSol{j}.y(yInd+1, :)];
                    Iload = [Iload odeSol{j}.y(yInd + sum(WS.Nx), :)];
                    t = [t odeSol{j}.x];
                    Eload = cumtrapz(t,Vload.*Iload);
                end

                %plots voltage and current respectively
                %odeSol.y(1:sum(WS.Nx)) is voltage, 
                %odeSol.y(sum(WS.Nx)+1:end) is current.
                plot(app.UIAxes1, D.reDim_t(t).*10^9, Vload);
                plot(app.UIAxes2, D.reDim_t(t).*10^9, Iload);
                plot(app.UIAxes4, D.reDim_t(t).*10^9, Eload);
                app.legendCell{end+1} = num2str(loadLocations(i), 'Load @ %-g m');
            end
            
        end
        
        function [plotInd, modWL] = getplotInd(app, loc, breaks)
            %find the cable on which desired location is
            %for loads, this will return the right Cable

            %take mod of the location at every cable interface
            modWL = mod(loc, breaks);
            
            %index of the mod vector that corresponds to the location
            plotInd = find(modWL == loc, 1);
            
            %if index on the mod vector is not found, means that the
            %location is on the last Cable
            if isempty(plotInd)
                plotInd = length(modWL);
            end
        end
        
        function [loadLocations] = findLoads(app, lengths)
            %finds locations of loads in m from the pulser 
            ind = find(lengths == 0); 
            res = cumsum(lengths);  
            loadLocations = res(ind);
        end
        
        function [rout, cout] = constructLoads(app, rin, cin, type)
            %pads the r and c vectors such that every interface has an
            %implied load: w-w interfaces have a load with 0, and input
            %loads and placed in the appropriate w-l interfaces
            
            rout = []; cout = [];
            for i = 1:length(type)-1
                if (type(i) == "Cable" && type(i+1) == "Cable")
                    rout = [rout 0];
                    cout = [cout 0];
                elseif (type(i) == "Cable" && type(i+1) ~= "Cable")
                    rout = [rout rin(i+1)];
                    cout = [cout cin(i+1)];
                elseif (type(i) ~= "Cable" && type(i+1) == "Cable")
                    continue
                end
            end
        end
        
        
        function [value, isterminal, direction] = spark_event(app, t,y,WS, load_index, thresh, dir)
        %SPARK_EVENT Summary of this function goes here
        %   Detailed explanation goes here
            dV=y(sum(WS.Nx(1:load_index)))-y(sum(WS.Nx(1:load_index))+1);
            value=dV-thresh;
            isterminal = 1;
            direction = dir;
        
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LeftPanel.AutoResizeChildren = 'off';
            app.RightPanel.AutoResizeChildren = 'off';
            drawnow;
            pause(2);


            this_file=mfilename('fullpath'); % full path
            tmp=mfilename(); % just the name of this file
            tmp2=strsplit(this_file,tmp);
            addpath Logos;
            addpath Data;
            addpath Objects;

            app.Image.ImageSource =  'apg_logo_clear_background.png';
            app.Image2.ImageSource = 'VIViewLogo.png';
            app.Image3.ImageSource = 'MIT-logo-black-red.png';
            
            xlabel(app.UIAxes1,'Time (ns)','Interpreter','Latex');
            ylabel(app.UIAxes1, '$\left(V/V_{ch}\right)$','Interpreter','Latex');

            xlabel(app.UIAxes2,'Time (ns)','Interpreter','Latex');
            ylabel(app.UIAxes2, '$\left(I/I_{ch}\right)$','Interpreter','Latex');

            yyaxis(app.UIAxes3, "left");
            ylim(app.UIAxes3, [-2, 2]);
            ylabel(app.UIAxes3, '$\left(V/V_{ch}\right)$','Interpreter','Latex');
            yyaxis(app.UIAxes3, "right");
            ylim(app.UIAxes3, [-2, 2]);
            ylabel(app.UIAxes3, '$\left(I/I_{ch}\right)$','Interpreter','Latex')
            xlabel(app.UIAxes3, 'Position $\left(x/L\right)$','Interpreter','Latex');
            
            xlabel(app.UIAxes4,'Time (ns)','Interpreter','Latex');
            ylabel(app.UIAxes4, '$\left(E/E_{ch}\right)$','Interpreter','Latex');
            movegui(app.UIFigure,'center');
            app.UIFigure.Resize = 'off';
            app.LeftPanel.AutoResizeChildren = 'off';
            app.RightPanel.AutoResizeChildren = 'off';
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {791, 791};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {609, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: AddElementButton
        function AddElementButtonPushed(app, event)
            if app.ElementTypeDropDown.Value == "..."
                return
            end
            app.UITable.Data = app.data;
            type = app.ElementTypeDropDown.Value;
            len = app.CableLengthEditField.Value;
            rnondim = app.CableZcEditField.Value;
            unondim = app.unondimEditField.Value;
            r = app.REditField.Value;
            c = app.CEditField.Value;
            gridspacing = app.ofPointsEditField.Value;
            add = {type len rnondim unondim r c gridspacing};
            app.UITable.Data = [app.data; add];
            app.data = app.UITable.Data;

            app.CableLengthEditField.Value = 0;
            app.CableZcEditField.Value = 0;
            app.unondimEditField.Value = 0;
            app.REditField.Value = 0;
            app.CEditField.Value = 0;
            app.ofPointsEditField.Value = 0;
        end

        % Value changed function: ElementTypeDropDown
        function ElementTypeDropDownValueChanged(app, event)
            value = app.ElementTypeDropDown.Value;
            if value == "Cable"
                app.OhmsLabel.Visible = 'off';
                app.OhmsLabel2.Visible = 'on';
                app.FaradsLabel.Visible = 'off';
                app.metersLabel.Visible = 'on';
                app.REditField.Visible = 'off';
                app.REditFieldLabel.Visible = 'off';
                app.CEditField.Visible = 'off';
                app.CEditFieldLabel.Visible = 'off';
                app.CableZcEditField.Visible = 'on';
                app.CableZcEditFieldLabel.Visible = 'on';
                app.unondimEditField.Visible = 'on';
                app.unondimEditFieldLabel.Visible = 'on';
                app.CableLengthEditField.Visible = 'on';
                app.CableLengthEditFieldLabel.Visible = 'on';
                app.ofPointsEditField.Visible = 'on';
                app.ofPointsEditFieldLabel.Visible = 'on';
                app.VbreakdownEditField.Visible = 'off';
                app.VbreakdownEditFieldLabel.Visible = 'off';
                app.VLabel.Visible = 'off';
                app.REditField.Value = 0;
                app.CEditField.Value = 0;
                app.ofPointsEditField.Value = 0;
                app.CableLengthEditField.Value = 0;
                app.unondimEditField.Value = 0;
                app.CableZcEditField.Value = 0;
            elseif value == "Load"
                app.OhmsLabel.Visible = 'on';
                app.OhmsLabel2.Visible = 'off';
                app.FaradsLabel.Visible = 'on';
                app.metersLabel.Visible = 'off';
                app.REditField.Visible = 'on';
                app.REditFieldLabel.Visible = 'on';
                app.REditField.Value = 0;
                app.CEditField.Visible = 'on';
                app.CEditField.Value = 0;
                app.CEditFieldLabel.Visible = 'on';
                app.CableZcEditField.Visible = 'off';
                app.CableZcEditFieldLabel.Visible = 'off';
                app.unondimEditField.Visible = 'off';
                app.unondimEditFieldLabel.Visible = 'off';
                app.CableLengthEditField.Visible = 'off';
                app.CableLengthEditFieldLabel.Visible = 'off';
                app.ofPointsEditField.Visible = 'off';
                app.ofPointsEditFieldLabel.Visible = 'off';
                app.VbreakdownEditField.Visible = 'off';
                app.VbreakdownEditFieldLabel.Visible = 'off';
                app.VLabel.Visible = 'off';
                app.ofPointsEditField.Value = 0;
                app.CableLengthEditField.Value = 0;
                app.unondimEditField.Value = 0;
                app.CableZcEditField.Value = 0;
            elseif value == "Spark Gap"
                app.OhmsLabel.Visible = 'off';
                app.OhmsLabel2.Visible = 'off';
                app.FaradsLabel.Visible = 'on';
                app.metersLabel.Visible = 'off';
                app.REditField.Visible = 'off';
                app.REditField.Value = 0;
                app.REditFieldLabel.Visible = 'off';
                app.CEditField.Visible = 'on';
                app.CEditField.Value = 0;
                app.CEditFieldLabel.Visible = 'on';
                app.CableZcEditField.Visible = 'off';
                app.CableZcEditFieldLabel.Visible = 'off';
                app.CableZcEditField.Value = 0;
                app.unondimEditField.Visible = 'off';
                app.unondimEditFieldLabel.Visible = 'off';
                app.unondimEditField.Value = 0;
                app.CableLengthEditField.Visible = 'off';
                app.CableLengthEditFieldLabel.Visible = 'off';
                app.CableLengthEditField.Value = 0;
                app.ofPointsEditField.Visible = 'off';
                app.ofPointsEditFieldLabel.Visible = 'off';
                app.ofPointsEditField.Value = 0;
                app.VbreakdownEditField.Visible = 'on';
                app.VbreakdownEditFieldLabel.Visible = 'on';
                app.VLabel.Visible = 'on';
            elseif value == "..."
                app.OhmsLabel.Visible = 'on';
                app.OhmsLabel2.Visible = 'on';
                app.FaradsLabel.Visible = 'on';
                app.metersLabel.Visible = 'on';
                app.REditField.Visible = 'on';
                app.REditField.Value = 0;
                app.CEditField.Value = 0;
                app.REditFieldLabel.Visible = 'on';
                app.CEditField.Visible = 'on';
                app.CEditFieldLabel.Visible = 'on';
                app.CableZcEditField.Visible = 'on';
                app.CableZcEditFieldLabel.Visible = 'on';
                app.unondimEditField.Visible = 'on';
                app.unondimEditFieldLabel.Visible = 'on';
                app.CableLengthEditField.Visible = 'on';
                app.CableLengthEditFieldLabel.Visible = 'on';
                app.ofPointsEditField.Visible = 'on';
                app.ofPointsEditFieldLabel.Visible = 'on';
                app.VbreakdownEditField.Visible = 'on';
                app.VbreakdownEditFieldLabel.Visible = 'on';
                app.VLabel.Visible = 'on';
                app.ofPointsEditField.Value = 0;
                app.CableLengthEditField.Value = 0;
                app.unondimEditField.Value = 0;
                app.CableZcEditField.Value = 0;
            end
            
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            app.ProgressEditField1.BackgroundColor = [1.00,1.00,1.00];
            app.ProgressEditField2.BackgroundColor = [1.00,1.00,1.00];
            app.ProgressEditField3.BackgroundColor = [1.00,1.00,1.00];
            app.ProgressEditField4.BackgroundColor = [1.00,1.00,1.00];
            app.ProgressEditField5.BackgroundColor = [1.00,1.00,1.00];
            
            app.TimeSlider.Value = 1;
            app.legendCell = {};
            
            cla(app.UIAxes1);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            cla(app.UIAxes4);
            
            teleMain(app);
        end

        % Button pushed function: DeleteElementButton
        function DeleteElementButtonPushed(app, event)
            app.UITable.Data = app.data;
            app.UITable.Data = app.data(1:end-1, :);
            app.data = app.UITable.Data;
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.TimeSlider.Value = round(app.TimeSlider.Value, 0);
            while app.TimeSlider.Value < app.Np
               if app.TimeSlider.Value > app.Np - 10
                   app.TimeSlider.Value = app.Np;
               else
                   app.TimeSlider.Value = app.TimeSlider.Value + 10;
               end
               TimeSliderValueChanging(app, event);
               pause(0.075);
            end

        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            cla(app.UIAxes1);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            cla(app.UIAxes4);
            app.TimeSlider.Value = 1;
            app.ProbeLocationEditField.Value = '';
            app.LoadPlotsCheckBox.Value = 1;
            app.ProgressBarLabel.Text = 'Progress Bar';
            app.ProgressEditField1.BackgroundColor = [1.00,1.00,1.00];
            app.ProgressEditField2.BackgroundColor = [1.00,1.00,1.00];
            app.ProgressEditField3.BackgroundColor = [1.00,1.00,1.00];
            app.ProgressEditField4.BackgroundColor = [1.00,1.00,1.00];
            app.ProgressEditField5.BackgroundColor = [1.00,1.00,1.00];
            
            app.UITable.Data = app.data;
            app.UITable.Data = {};
            app.data = app.UITable.Data;
            clear all;
            
        end

        % Value changing function: TimeSlider
        function TimeSliderValueChanging(app, event)
            %changingValue = event.Value;
            value = app.TimeSlider.Value;
            cla(app.UIAxes3)
            yyaxis(app.UIAxes3, "left");
            ylim(app.UIAxes3, [-2, 2]);
            yyaxis(app.UIAxes3, "right");
            ylim(app.UIAxes3, [-2, 2]);
            timestamp = value;
            timestamp = round(timestamp, 0);
            hold(app.UIAxes3, "on");
            plot(app.UIAxes3, app.X, app.curr(:, timestamp));
            plot(app.UIAxes3, app.X, app.volt(:, timestamp), '-b');
            for i = 1:length(app.Breaks)
                plot(app.UIAxes3, [app.Breaks(i);app.Breaks(i)], [-2;2], '--k');
            end
            hold(app.UIAxes3, "off");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Image3 = uiimage(app.LeftPanel);
            app.Image2 = uiimage(app.LeftPanel);
            app.Image = uiimage(app.LeftPanel);
            app.VoltageInputDataEditFieldLabel = uilabel(app.LeftPanel);
            app.VoltageInputDataEditFieldLabel.Text = 'Voltage Input Data';
            app.VoltageInputDataEditField = uieditfield(app.LeftPanel, 'text');
            app.VoltageInputDataEditField.Value = 'Pulse_300VDC.mat';
            app.SampleGaussianCheckBox = uicheckbox(app.LeftPanel);
            app.SampleGaussianCheckBox.Text = 'Sample Gaussian';
            app.ElementTypeDropDownLabel = uilabel(app.LeftPanel);
            app.ElementTypeDropDownLabel.Text = 'Element Type';
            app.ElementTypeDropDown = uidropdown(app.LeftPanel);
            app.ElementTypeDropDown.Items = {'...', 'Cable', 'Load', 'Spark Gap'};
            app.ElementTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ElementTypeDropDownValueChanged, true);
            app.ElementTypeDropDown.Value = '...';
            app.REditFieldLabel = uilabel(app.LeftPanel);
            app.REditFieldLabel.Text = 'R';
            app.OhmsLabel = uilabel(app.LeftPanel);
            app.OhmsLabel.Text = 'Ohms';
            app.REditField = uieditfield(app.LeftPanel, 'numeric');
            app.CEditFieldLabel = uilabel(app.LeftPanel);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FaradsLabel = uilabel(app.LeftPanel);
            app.FaradsLabel.Text = 'Farads';
            app.VbreakdownEditFieldLabel = uilabel(app.LeftPanel);
            app.VbreakdownEditFieldLabel.Text = 'Vbreakdown';
            app.VbreakdownEditField = uieditfield(app.LeftPanel, 'numeric');
            app.VLabel = uilabel(app.LeftPanel);
            app.VLabel.Text = 'V';
            app.CableLengthEditFieldLabel = uilabel(app.LeftPanel);
            app.CableLengthEditFieldLabel.Text = 'Cable Length';
            app.CableLengthEditField = uieditfield(app.LeftPanel, 'numeric');
            app.metersLabel = uilabel(app.LeftPanel);
            app.metersLabel.Text = 'meters';
            app.CableZcEditFieldLabel = uilabel(app.LeftPanel);
            app.CableZcEditFieldLabel.Text = 'Cable Zc';
            app.CableZcEditField = uieditfield(app.LeftPanel, 'numeric');
            app.OhmsLabel2 = uilabel(app.LeftPanel);
            app.OhmsLabel2.Text = 'Ohms';
            app.unondimEditFieldLabel = uilabel(app.LeftPanel);
            app.unondimEditFieldLabel.Text = 'u (non-dim)';
            app.unondimEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ofPointsEditFieldLabel = uilabel(app.LeftPanel);
            app.ofPointsEditFieldLabel.Text = '# of Points';
            app.ofPointsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AddElementButton = uibutton(app.LeftPanel, 'push');
            app.AddElementButton.ButtonPushedFcn = createCallbackFcn(app, @AddElementButtonPushed, true);
            app.AddElementButton.Text = 'Add Element';
            app.DeleteElementButton = uibutton(app.LeftPanel, 'push');
            app.DeleteElementButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteElementButtonPushed, true);
            app.DeleteElementButton.Text = 'Delete Element';
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'Element'; 'Cable Length (m)'; 'Zc (Ohms)'; 'u (non-dim)'; 'Load R (Ohms)'; 'Load C (F)'; '# of Points'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.ProbeLocationEditFieldLabel = uilabel(app.LeftPanel);
            app.ProbeLocationEditFieldLabel.Text = 'Probe Location';
            app.ProbeLocationEditField = uieditfield(app.LeftPanel, 'text');
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '';
            app.ProbeLocationLabel = uilabel(app.LeftPanel);
            app.ProbeLocationLabel.Text = '<input> meters, where <input> is between 0 and sum(Cable Length)';
            app.LoadPlotsCheckBox = uicheckbox(app.LeftPanel);
            app.LoadPlotsCheckBox.Text = 'Load Plots';
            app.LoadPlotsCheckBox.Value = true;
            app.LoadPlotsLabel = uilabel(app.LeftPanel);
            app.LoadPlotsLabel.Text = 'Check if you want to plot voltage drop across load ';
            app.CharPeakVoltageEditField_2Label = uilabel(app.LeftPanel);
            app.CharPeakVoltageEditField_2Label.Text = 'Char. Peak Voltage';
            app.CharPeakVoltageEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CharPeakVoltageLabel = uilabel(app.LeftPanel);
            app.CharPeakVoltageLabel.Text = 'V';
            app.CharImpedanceEditFieldLabel = uilabel(app.LeftPanel);
            app.CharImpedanceEditFieldLabel.Text = 'Char. Impedance';
            app.CharImpedanceEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CharLengthEditFieldLabel = uilabel(app.LeftPanel);
            app.CharLengthEditFieldLabel.Text = 'Char. Length';
            app.OhmsLabel3 = uilabel(app.LeftPanel);
            app.OhmsLabel3.Text = 'Ohms';
            app.CharLengthEditField = uieditfield(app.LeftPanel, 'numeric');
            app.metersLabel2 = uilabel(app.LeftPanel);
            app.metersLabel2.Text = 'meters';
            app.MaxTimeEditFieldLabel = uilabel(app.LeftPanel);
            app.MaxTimeEditFieldLabel.Text = 'Max Time';
            app.MaxTimeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MaxTimeEditField.Value = 5e-07;
            app.secsLabel = uilabel(app.LeftPanel);
            app.secsLabel.Text = 'secs';
            app.TimestepsEditFieldLabel = uilabel(app.LeftPanel);
            app.TimestepsEditFieldLabel.Text = 'Timesteps';
            app.TimestepsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TimestepsEditField.Value = 5000;
            app.ExtractSolutionVecsCheckBox = uicheckbox(app.LeftPanel);
            app.ExtractSolutionVecsCheckBox.Text = 'Extract Solution Vecs';
            app.ComputeButton = uibutton(app.LeftPanel, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.ProgressBarLabel = uilabel(app.LeftPanel);
            app.ProgressBarLabel.Text = 'Progress Bar';
            app.ProgressEditField1 = uieditfield(app.LeftPanel, 'text');
            app.ProgressEditField1.Editable = 'off';
            app.ProgressEditField2 = uieditfield(app.LeftPanel, 'text');
            app.ProgressEditField2.Editable = 'off';
            app.ProgressEditField3 = uieditfield(app.LeftPanel, 'text');
            app.ProgressEditField3.Editable = 'off';
            app.ProgressEditField4 = uieditfield(app.LeftPanel, 'text');
            app.ProgressEditField4.Editable = 'off';
            app.ProgressEditField5 = uieditfield(app.LeftPanel, 'text');
            app.ProgressEditField5.Editable = 'off';
            app.ClearAllButton = uibutton(app.LeftPanel, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Current vs Time')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'V and I vs x')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes1 = uiaxes(app.RightPanel);
            title(app.UIAxes1, 'Voltage vs Time')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            app.UIAxes4 = uiaxes(app.RightPanel);
            title(app.UIAxes4, 'Energy vs Time')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.ProbeLocationLabel_2 = uilabel(app.RightPanel);
            app.ProbeLocationLabel_2.Text = 'Dashed lines in this figure resemble every discontinuous CableSystem element interface';
            app.TimeSliderLabel = uilabel(app.RightPanel);
            app.TimeSliderLabel.Text = ' Time ';
            app.TimeSlider = uislider(app.RightPanel);
            app.TimeSlider.Limits = [1 1001];
            app.TimeSlider.MajorTicks = [1 101 201 301 401 501 601 701 801 901 1001];
            app.TimeSlider.MajorTickLabels = {''};
            app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @TimeSliderValueChanging, true);
            app.TimeSlider.MinorTicks = [];
            app.TimeSlider.Value = 1;
            app.PlayButton = uibutton(app.RightPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.TimeSliderLabel_2 = uilabel(app.RightPanel);
            app.TimeSliderLabel_2.Text = '0';
            app.TimeSliderLabel_3 = uilabel(app.RightPanel);
            app.TimeSliderLabel_3.Text = 'End';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VIView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end